classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label                         matlab.ui.control.Label
        PatienceEditField             matlab.ui.control.NumericEditField
        PatienceEditFieldLabel        matlab.ui.control.Label
        PROJECTButton                 matlab.ui.control.Button
        RelaxationCheckBox            matlab.ui.control.CheckBox
        SARTUseWindowCheckBox         matlab.ui.control.CheckBox
        PhantomNameEditField          matlab.ui.control.EditField
        PhantomNameLabel              matlab.ui.control.Label
        NumberofDetectorsEditField    matlab.ui.control.NumericEditField
        NumberofDetectorsEditFieldLabel  matlab.ui.control.Label
        NumberofIterationaEditField   matlab.ui.control.NumericEditField
        NumberofIterationaEditFieldLabel  matlab.ui.control.Label
        SourcetoDetectordistanceEditField  matlab.ui.control.NumericEditField
        SourcetoDetectordistanceEditFieldLabel  matlab.ui.control.Label
        ProjectionAngleStepSizeEditField  matlab.ui.control.NumericEditField
        ProjectionAngleStepSizeLabel  matlab.ui.control.Label
        LengthoftheDetectorEditField  matlab.ui.control.NumericEditField
        LengthoftheDetectorEditFieldLabel  matlab.ui.control.Label
        UIAxes_8                      matlab.ui.control.UIAxes
        UIAxes_7                      matlab.ui.control.UIAxes
        UIAxes_6                      matlab.ui.control.UIAxes
        UIAxes_5                      matlab.ui.control.UIAxes
        UIAxes_4                      matlab.ui.control.UIAxes
        UIAxes_3                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PROJECTButton
        function PROJECTButtonPushed(app, event)
            %%%%%  THIS PLACE IS GOING TO BE FILLED
            phantom_name = app.PhantomNameEditField.Value;
            I = struct2array(load(phantom_name));
            N_pix = size(I,1);
            show_plot = 1;
            L_det = app.LengthoftheDetectorEditField.Value;
            N_det = app.NumberofDetectorsEditField.Value;
            n_iter = app.NumberofIterationaEditField.Value;
            source2det_dist = app.SourcetoDetectordistanceEditField.Value;
            angle_step = app.ProjectionAngleStepSizeEditField.Value;
            patience = app.PatienceEditField.Value;
            [PROJECTIONS, gammas] = radon_project(phantom_name,L_det, N_det, angle_step, source2det_dist);
            app.Label.BackgroundColor = 'white';
            app.Label.FontSize = 20;
            app.Label.Text = ['[INFO]' newline 'PROJECTION COMPLETED'];
            [art_IMAGE, art_errors] = art(N_pix, N_pix, PROJECTIONS, L_det, source2det_dist, n_iter, show_plot, I, patience, 1, app.UIAxes);
            app.Label.Text = ['[INFO]' newline 'ART COMPLETED'];


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ART')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Relative Error')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Modified ')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'SIRT')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'SART')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Title')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.LengthoftheDetectorEditFieldLabel = uilabel(app.UIFigure);
            app.LengthoftheDetectorEditFieldLabel.Text = {'Length of the'; 'Detector'};
            app.LengthoftheDetectorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ProjectionAngleStepSizeLabel = uilabel(app.UIFigure);
            app.ProjectionAngleStepSizeLabel.Text = {'Projection Angle'; 'Step Size'};
            app.ProjectionAngleStepSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SourcetoDetectordistanceEditFieldLabel = uilabel(app.UIFigure);
            app.SourcetoDetectordistanceEditFieldLabel.Text = {'Source to '; 'Detector distance'};
            app.SourcetoDetectordistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofIterationaEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofIterationaEditFieldLabel.Text = 'Number of Iterationa';
            app.NumberofIterationaEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofDetectorsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofDetectorsEditFieldLabel.Text = {'Number of '; 'Detectors'};
            app.NumberofDetectorsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhantomNameLabel = uilabel(app.UIFigure);
            app.PhantomNameLabel.Text = 'Phantom Name';
            app.PhantomNameEditField = uieditfield(app.UIFigure, 'text');
            app.SARTUseWindowCheckBox = uicheckbox(app.UIFigure);
            app.SARTUseWindowCheckBox.Text = 'SART: Use Window';
            app.RelaxationCheckBox = uicheckbox(app.UIFigure);
            app.RelaxationCheckBox.Text = 'Relaxation';
            app.PROJECTButton = uibutton(app.UIFigure, 'push');
            app.PROJECTButton.ButtonPushedFcn = createCallbackFcn(app, @PROJECTButtonPushed, true);
            app.PROJECTButton.Text = 'PROJECT';
            app.PatienceEditFieldLabel = uilabel(app.UIFigure);
            app.PatienceEditFieldLabel.Text = 'Patience';
            app.PatienceEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end